classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DisplayButton             matlab.ui.control.Button
        InformationTextArea       matlab.ui.control.TextArea
        InformationTextAreaLabel  matlab.ui.control.Label
        ParameterPanel            matlab.ui.container.Panel
        StartingDate3daysdurationDatePicker  matlab.ui.control.DatePicker
        StartingDate3daysdurationDatePickerLabel  matlab.ui.control.Label
        El_MaskEditField          matlab.ui.control.NumericEditField
        El_MaskEditFieldLabel     matlab.ui.control.Label
        GNSSDropDown              matlab.ui.control.DropDown
        GNSSDropDownLabel         matlab.ui.control.Label
        HeightEditField           matlab.ui.control.NumericEditField
        HeightEditFieldLabel      matlab.ui.control.Label
        LonEditField              matlab.ui.control.NumericEditField
        LonEditFieldLabel         matlab.ui.control.Label
        LatEditField              matlab.ui.control.NumericEditField
        LatEditFieldLabel         matlab.ui.control.Label
        SkyPlotPanel              matlab.ui.container.Panel
        GroundTrackPanel          matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties
        GNSS
        %% geoplot append matrix
        lat_geoplot
        lon_geoplot
        %% Ground Station %%
        ground_lat
        ground_lon
        height
        el_mask
        %% skyplot append matrix
        az_skyplot
        el_skyplot
        a     %Semi-major axis
        e     %eccentricity
        i     %inclination angle
        omega %Argument of perigee
        M0    % Mean anomaly at toc
        toc   %Epoch
        OMEGA %Right ascension of ascending node
        %% Time setting
        t1
        t2
        t1_2
        ttime
        h
        %% sdfasf
        gx
        %% lat,lon,height,el value_input
        lat_value
        lon_value
        height_value
        el_value
        %% ECI save matrix
        ECI_
        %% t1 set
        set_t1
        bettime
    end
    methods (Access = private)
        
        function GNSS_Select(app)
            clf('reset');
            load('nav.mat');
            if strcmp(app.GNSSDropDown.Value,'GPS')
                app.GNSS = nav.GPS;
            elseif strcmp(app.GNSSDropDown.Value,'QZSS')
                app.GNSS = nav.QZSS;
            else
                app.GNSS = nav.BDS;
            end
        end

%% init
        function init(app)
        app.a = app.GNSS.a/1000;  % Semi-major axis
        app.e = app.GNSS.e;  % eccentricity
        app.i = rad2deg(app.GNSS.i);  % inclination angle
        app.omega = rad2deg(app.GNSS.omega); %Argument of perigee 
        app.M0 = app.GNSS.M0; % Mean anomaly at toc
        app.toc = app.GNSS.toc; %Epoch
        app.OMEGA = rad2deg(app.GNSS.OMEGA); %Right ascension of ascending node
        %% geoplot append matrix
        app.lat_geoplot = [];
        app.lon_geoplot = [];
        %% skyplot append matrix
        app.az_skyplot = [];
        app.el_skyplot = [];
        %% ECI save matrix
        app.ECI_ = [];
        %% Ground Station %%
        TF_lat = isempty(app.lat_value);
        if TF_lat == 1
            app.ground_lat = 37; %[deg]
        else 
            app.ground_lat = app.lat_value;
        end

        TF_lon = isempty(app.lon_value);
        if TF_lon == 1
            app.ground_lon = 127; %[deg]
        else 
            app.ground_lon = app.lon_value;
        end

        TF_height = isempty(app.height_value);
        if TF_height == 1
            app.height = 1; %[km]
        else
            app.height = app.height_value;
        end
        
        TF_el = isempty(app.el_value);
        if TF_el == 1
            app.el_mask = 10; %[deg]
        else
            app.el_mask = app.el_value;
        end

        %% Time setting
        
        TF_t1 = isempty(app.set_t1);
        if TF_t1 == 1
            app.t1 = datetime(app.toc); %[deg]
        else
            app.t1 = app.set_t1;
        end
        app.t2 = datetime(app.t1)+days(3);
        app.bettime = datetime(app.t2)-datetime(app.t1);
        hours_bettime = hours(app.bettime);
        app.ttime = linspace(app.t1,app.t2,hours_bettime);
        end


        function Orbit(app)
        %% Orbit
            for j=1:length(app.ttime)
                %% M0 2 M
                M = Mean_anomaly(app.t1,app.ttime(j),app.a,app.e,app.M0);
                %% M2E
                E = Mean2Eccen(M,app.e);
                %% E2v
                v = E2v(E,app.e);
                %% Range in Perifocal frame
                rangeInPQW = solveRangeInPerifocalFrame(app.a,app.e,v);
                %% Perifocal 2 ECI frame
                DCM_P2I=PQW2ECI(app.omega,app.i,app.OMEGA);
                rangeInECI=(DCM_P2I)*rangeInPQW;
                %% ECI 2 ECEF frame
                DCM = ECI2ECEF_DCM(app.ttime(j));
                rangeInECEF=DCM*rangeInECI;
                app.ECI_ = [app.ECI_;rangeInECI'];
                %% ECEF 2 geodetic
                wgs84 = wgs84Ellipsoid('kilometer');
                [lat,lon,app.h] = ecef2geodetic(wgs84,rangeInECEF(1),rangeInECEF(2),rangeInECEF(3),"degrees");

                % geodetic -> geoplot
                app.lat_geoplot = [app.lat_geoplot,lat];
                app.lon_geoplot = [app.lon_geoplot,lon];
                %% ECEF 2 ENU
                wgs84 = wgs84Ellipsoid('kilometer');
                [rangeInENU(1),rangeInENU(2),rangeInENU(3)] = ecef2enu(rangeInECEF(1),rangeInECEF(2),rangeInECEF(3),...
                 app.ground_lat,app.ground_lon,app.height,wgs84);
                rangeInENU = [rangeInENU(1);rangeInENU(2);rangeInENU(3)];
                %% ENU 2 Azimuth/Elevation
                az = azimuth(rangeInENU);
                el = elevation(rangeInENU, app.el_mask);
                app.az_skyplot = [app.az_skyplot,az];
                app.el_skyplot = [app.el_skyplot,el];
            end
        end

        function g_plot(app)
            delete(app.gx)
            app.gx=geoaxes(app.GroundTrackPanel);
            lat_gplot = app.lat_geoplot;
            lon_gplot = app.lon_geoplot;
            geobasemap(app.gx, 'colorterrain');
            geoplot(app.gx,app.ground_lat,app.ground_lon,'b-*','MarkerSize', 12);
            hold(app.gx, 'on');
            geoplot(app.gx,lat_gplot,lon_gplot,'r-*');
            end

        function s_plot(app)
            clf('reset');
            az_splot = app.az_skyplot;
            el_splot = app.el_skyplot;
            for e_1 = 1:length(az_splot)
            skyplot(app.SkyPlotPanel,az_splot(e_1),el_splot(e_1));
            drawnow
            end
        end

        function orbit_plot(app)
            cla(app.UIAxes);
            plot3(app.UIAxes, app.ECI_(:,1), app.ECI_(:,2), app.ECI_(:,3));
            hold(app.UIAxes, 'on');
            plot3(app.UIAxes, 0, 0, 0, 'Marker', 'o', 'MarkerSize', 50, 'MarkerFaceColor', 'blue', 'MarkerEdgeColor', 'blue');
            grid(app.UIAxes, 'on');
        end

        function text(app)
            memo = 'hello';
            disp([app.InformationTextArea, memo]);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('nav.mat');
            app.GNSS = nav.GPS;
            app.init()
            app.Orbit()
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.orbit_plot()
        end

        % Button down function: SkyPlotPanel
        function SkyPlotPanelButtonDown(app, event)
            clf("reset");
            app.s_plot()
        end

        % Button down function: GroundTrackPanel
        function GroundTrackPanelButtonDown(app, event)
            app.g_plot()
        end

        % Value changed function: LatEditField
        function LatEditFieldValueChanged(app, event)
            app.lat_value = app.LatEditField.Value;
            app.init()
            app.Orbit()
            
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
        end

        % Value changed function: GNSSDropDown
        function GNSSDropDownValueChanged(app, event)
        app.GNSS_Select()
        disp(app.GNSS)
        app.init()
        app.Orbit()
        
        app.orbit_plot()
        app.g_plot()
        app.s_plot()
        end

        % Value changed function: LonEditField
        function LonEditFieldValueChanged(app, event)
            app.lon_value = app.LonEditField.Value;
            app.init()
            app.Orbit()
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.height_value = app.HeightEditField.Value;
            app.init()
            app.Orbit()
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
        end

        % Value changed function: El_MaskEditField
        function El_MaskEditFieldValueChanged(app, event)
            app.el_value = app.El_MaskEditField.Value;
            app.init()
            app.Orbit()
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
        end

        % Value changed function: StartingDate3daysdurationDatePicker
        function StartingDate3daysdurationDatePickerValueChanged(app, event)
            value = app.StartingDate3daysdurationDatePicker.Value;
            app.set_t1 = datetime(value);
            disp(app.set_t1)
            app.init();
            app.Orbit();
            app.orbit_plot()
            app.g_plot()
            app.s_plot()
            disp(size(app.ECI_))
        end

        % Value changed function: InformationTextArea
        function InformationTextAreaValueChanged2(app, event)
            value = app.InformationTextArea.Value;
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            message = ['---Orbit Parameter---';...
            'Semi-major axis : ' + string(app.a) + '[km]';...
            'Eccentricity : ' + string(app.e);...
            'Inclination angle : ' + string(app.i) + '[deg]';...
            'Argument of Perigee : ' + string(app.omega) + '[deg]';...
            'Right ascension of ascending node : '+string(app.OMEGA) + '[deg]';...
            ;...
            '---Ground Station---';...
            'LAT : '+string(app.ground_lat);...
            'LON : '+string(app.ground_lon);...
            'Height : '+string(app.height)];
            app.InformationTextArea.Value = message;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Orbit')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'GroundTrack';
            app.GroundTrackPanel.ButtonDownFcn = createCallbackFcn(app, @GroundTrackPanelButtonDown, true);
            app.SkyPlotPanel = uipanel(app.UIFigure);
            app.SkyPlotPanel.Title = 'SkyPlot';
            app.SkyPlotPanel.ButtonDownFcn = createCallbackFcn(app, @SkyPlotPanelButtonDown, true);
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.LatEditFieldLabel = uilabel(app.ParameterPanel);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LatEditField.ValueChangedFcn = createCallbackFcn(app, @LatEditFieldValueChanged, true);
            app.LatEditField.Value = 37;
            app.LonEditFieldLabel = uilabel(app.ParameterPanel);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.LonEditField.ValueChangedFcn = createCallbackFcn(app, @LonEditFieldValueChanged, true);
            app.LonEditField.Value = 127;
            app.HeightEditFieldLabel = uilabel(app.ParameterPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 1;
            app.GNSSDropDownLabel = uilabel(app.ParameterPanel);
            app.GNSSDropDownLabel.Text = 'GNSS';
            app.GNSSDropDown = uidropdown(app.ParameterPanel);
            app.GNSSDropDown.Items = {'GPS', 'QZSS', 'BDS'};
            app.GNSSDropDown.ValueChangedFcn = createCallbackFcn(app, @GNSSDropDownValueChanged, true);
            app.GNSSDropDown.Value = 'GPS';
            app.El_MaskEditFieldLabel = uilabel(app.ParameterPanel);
            app.El_MaskEditFieldLabel.Text = 'El_Mask';
            app.El_MaskEditField = uieditfield(app.ParameterPanel, 'numeric');
            app.El_MaskEditField.ValueChangedFcn = createCallbackFcn(app, @El_MaskEditFieldValueChanged, true);
            app.El_MaskEditField.Value = 10;
            app.StartingDate3daysdurationDatePickerLabel = uilabel(app.ParameterPanel);
            app.StartingDate3daysdurationDatePickerLabel.Text = {'Starting Date'; '(3days duration)'};
            app.StartingDate3daysdurationDatePicker = uidatepicker(app.ParameterPanel);
            app.StartingDate3daysdurationDatePicker.DisplayFormat = 'uuuu년 M월 d일';
            app.StartingDate3daysdurationDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartingDate3daysdurationDatePickerValueChanged, true);
            app.StartingDate3daysdurationDatePicker.Value = datetime([2023 4 29]);
            app.InformationTextAreaLabel = uilabel(app.UIFigure);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.ValueChangedFcn = createCallbackFcn(app, @InformationTextAreaValueChanged2, true);
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end